classdef freezing_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        ImportVideoMenu               matlab.ui.container.Menu
        ImportMATFileMenu             matlab.ui.container.Menu
        ImportTemplateMenu            matlab.ui.container.Menu
        SaveResultMenu                matlab.ui.container.Menu
        SummaryFileMenu               matlab.ui.container.Menu
        IndividualFileMenu            matlab.ui.container.Menu
        SaveTemplateMenu              matlab.ui.container.Menu
        BatchMenu                     matlab.ui.container.Menu
        BatchImportMenu               matlab.ui.container.Menu
        BatchAnalyzeMenu              matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        SetROIMenu                    matlab.ui.container.Menu
        VideoPanel                    matlab.ui.container.Panel
        MainAxes1                     matlab.ui.control.UIAxes
        Sliderleft                    matlab.ui.control.Slider
        Sliderright                   matlab.ui.control.Slider
        Slidervideo                   matlab.ui.control.Slider
        NextButton                    matlab.ui.control.Button
        PreviousButton                matlab.ui.control.Button
        PlayButton                    matlab.ui.control.StateButton
        Sliderthreshold               matlab.ui.control.Slider
        Sliderylim                    matlab.ui.control.Slider
        MainAxes2                     matlab.ui.control.UIAxes
        MinimumFreezeDurationSpinnerLabel  matlab.ui.control.Label
        MinimumFreezeDurationSpinner  matlab.ui.control.Spinner
        ContrastSliderLabel           matlab.ui.control.Label
        ContrastSlider                matlab.ui.control.Slider
        AnalyzeButton                 matlab.ui.control.Button
        DrawEpochButton               matlab.ui.control.Button
        EditThreshold                 matlab.ui.control.NumericEditField
        AddSegmentButton              matlab.ui.control.Button
        SubAxes1                      matlab.ui.control.UIAxes
        Sliderthreshold2              matlab.ui.control.Slider
        DeleteSegmentButton           matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        FileList                      matlab.ui.control.ListBox
        FineTuneButton                matlab.ui.control.Button
        ViewResultButton              matlab.ui.control.Button
        ExperimentNameEditFieldLabel  matlab.ui.control.Label
        ExperimentNameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        filename
        pathname 
        batchfilename
        batchpathname
        video
        currentFrame
        pos_rect
        pos_border
        Ifull
        tfull
        framefull
        threshold
        thresline
        thresline2
        tq
        Iq
        frameq
        videoline
        interval
        fps
        freeze_epoch
        ha
        selectedrow
        results
        I
        ts
        template
        videoshown
    end
    
    methods (Access = public)
        
        function update_table(app)
            
            tstart=app.UITable.Data.Start;
            dur=app.UITable.Data.Duration;
            app.UITable.Data.End=tstart+dur;

            for i=1:length(tstart)

                Itemp=app.Ifull(app.tfull>=tstart(i) & app.tfull<=app.UITable.Data.End(i));

                app.UITable.Data.Freezing(i)=ffe(app,Itemp);
            end
                
        end
    
    
        function find_freezeing_epoch(app)
            % Find logical vector where A < threshold
            binaryVector = app.Ifull < app.threshold;
            % Label each region with a label - an "ID" number.
            [labeledVector, numRegions] = bwlabel(binaryVector);
            % Measure lengths of each region and the indexes
            measurements = regionprops(labeledVector, app.Ifull, 'Area', 'PixelValues');
            % Find regions where the area (length) are 3 or greater and
            % put the values into a cell of a cell array
            num_epoch=0;
            i=1;
            app.freeze_epoch={};
            disp(['Need at least ' num2str(round(app.MinimumFreezeDurationSpinner.Value/app.interval)) ' frames in between']);
            for k = 1 : numRegions
              if measurements(k).Area >= round(app.MinimumFreezeDurationSpinner.Value/app.interval)
                % Area (length) is 3 or greater, so store the values.
                app.freeze_epoch{i}.value = measurements(k).PixelValues;
                app.freeze_epoch{i}.duration=length(app.freeze_epoch{i}.value);
                indtemp=find(labeledVector==k);
                app.freeze_epoch{i}.x1=indtemp(1);
                app.freeze_epoch{i}.x2=indtemp(end);
                num_epoch=num_epoch+1;
                i=i+1;
              end
            end

            if num_epoch==1
                app.freeze_epoch{i}={};
            end
        end
    
        function flevel=ffe(app,A)
            % Find logical vector where A < threshold
            binaryVector = A < app.threshold;
            % Label each region with a label - an "ID" number.
            [labeledVector, numRegions] = bwlabel(binaryVector);
            % Measure lengths of each region and the indexes
            measurements = regionprops(labeledVector, A, 'Area', 'PixelValues');
            % Find regions where the area (length) are 3 or greater and
            % put the values into a cell of a cell array
            freeze_c=0;
            i=1;
            disp(['Need at least ' num2str(round(app.MinimumFreezeDurationSpinner.Value/app.interval)) ' frames in between']);
            for k = 1 : numRegions
              if measurements(k).Area >= round(app.MinimumFreezeDurationSpinner.Value/app.interval)
                % Area (length) is 3 or greater, so store the values.
                value = measurements(k).PixelValues;
                freeze_c=freeze_c+length(value);
                i=i+1;
              end
            end

            flevel=(freeze_c/length(A))*100;
        end
    
        function [t,I]=batchanalysis(app)
            x1=app.pos_border(1);
            x2=app.pos_border(2);
            y1=app.pos_border(3);
            y2=app.pos_border(4);
            app.tfull=0;
            videoFrame=rgb2gray(readFrame(app.video));
            frame1=videoFrame(y1:y2,x1:x2);
            if app.ContrastSlider.Value<255
                frame1(frame1>=app.ContrastSlider.Value)=255;
            end
            app.Ifull=[];
                        
            while hasFrame(app.video)               
                app.tfull=[app.tfull,app.video.currentTime];
                videoFrame=rgb2gray(readFrame(app.video)); 
                frame2=videoFrame(y1:y2,x1:x2,:);
                if app.ContrastSlider.Value<255
                    frame2(frame2>=app.ContrastSlider.Value)=255;     
                end
                dI=abs(frame2-frame1);
                app.Ifull=[app.Ifull,mean(dI(:))];
                frame1=frame2;            
            end
            app.Ifull=[0 app.Ifull];
            if isempty(app.template) %if there were no template imported, make sure the start and end value is readjusted
                Start=app.tfull(1);
                End=app.tfull(end);
                Duration=End-Start;
                Freezing=NaN;
                Name={'Whole session'};
                app.UITable.Data=table(Start,End,Duration,Freezing,Name);
            end
            update_table(app)
            t=app.tfull;
            I=app.Ifull;
            
        end
    
    end
    

    methods (Access = private)

        % Menu selected function: ImportVideoMenu
        function ImportVideoMenuSelected(app, event)
            warning off
            
            %Read single video file 
            [newfile,newpath] = uigetfile({'*.avi;*.mp4;*.wmv;*.asf'},'Single Video File Selector');
            app.video=VideoReader(fullfile(newpath,newfile));
            app.FileList.Items=cat(2,app.FileList.Items,{newfile});            
            %Read parameters from Config txt file if it exists
            if exist('Config.txt','file')
                disp('Config file found');
                fileID = fopen('Config.txt','r');
                formatSpec = '%s %f';
                A = textscan(fileID,formatSpec);
                app.threshold=A{2}(1);
                app.EditThreshold.Value=app.threshold;
                app.Sliderthreshold.Value=app.threshold;
                app.Sliderthreshold2.Value=app.threshold;
                app.MinimumFreezeDurationSpinner.Value=A{2}(2);
                app.ContrastSlider.Value=A{2}(3);                    
                fclose(fileID);
            else
                disp('No config file found');
            end
                
            if length(app.FileList.Items)==1
                app.pathname=newpath;
                app.filename=newfile;
                app.video=VideoReader(fullfile(app.pathname,app.filename));
            
                %crop the image
                ti=app.video.currentTime;
                videoFrame=rgb2gray(readFrame(app.video));
                %videoFrame=rgb2gray(videoFrame);
                tempfig=figure('units','pixels',...
                        'position',[100 300 800 600],...
                        'menubar','none',...
                        'name','ROI selection window');
                imshow(videoFrame);
                try
                    h_rect = drawrectangle();
                    app.pos_rect = round(h_rect.Position);
                catch
                    h_rect = imrect;
                    app.pos_rect = wait(h_rect);
                end
                x1=app.pos_rect(1);x2=app.pos_rect(1)+app.pos_rect(3);
                y1=app.pos_rect(2);y2=app.pos_rect(2)+app.pos_rect(4);  
                app.pos_border=[x1,x2,y1,y2];
                close(tempfig);
                imshow(videoFrame,[0 app.ContrastSlider.Value],'Parent',app.MainAxes2);
                app.currentFrame=videoFrame;
                app.MainAxes2.YLim=[y1 y2];
                app.MainAxes2.XLim=[x1 x2];
                app.interval=1/app.video.FrameRate;
                app.Slidervideo.Limits=[0 app.video.Duration];
                app.Slidervideo.MajorTicks=[0 app.video.Duration];
                app.Sliderleft.Limits=[0 app.video.Duration];
                app.Sliderright.Limits=[0 app.video.Duration];
                app.Sliderright.Value=app.video.Duration;

                
                app.ImportTemplateMenu.Enable = 'on';
                app.AnalyzeButton.Enable='on';
                app.DrawEpochButton.Enable='on';


            end
            
        end

        % Value changing function: Sliderylim
        function SliderylimValueChanging(app, event)
            changingValue = event.Value;
            app.MainAxes1.YLim=[0 max(app.Ifull)*changingValue];
            app.Sliderthreshold.Limits=app.MainAxes1.YLim;
            app.videoline.YData=app.MainAxes1.YLim;
        end

        % Value changing function: Sliderthreshold
        function SliderthresholdValueChanging(app, event)
            changingValue = event.Value;
            app.thresline.YData=[changingValue changingValue];
            app.threshold=changingValue;
            app.Sliderthreshold2.Value=changingValue;
            app.thresline2.XData=[changingValue changingValue];
            app.EditThreshold.Value=changingValue;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            r=indices(1);
            c=indices(2);
            if c~=5
                app.UITable.Data{r,c}=newData;
                app.UITable.Data{r,3}=app.UITable.Data{r,1}+app.UITable.Data{r,2};
                Itemp=app.Ifull(app.tfull>=app.UITable.Data{r,1} & app.tfull<=app.UITable.Data{r,3});
                app.UITable.Data{r,4}=ffe(app,Itemp);
            else
                app.UITable.Data{r,c}={newData};
            end
            
        end

        % Value changing function: Sliderleft
        function SliderleftValueChanging(app, event)
            changingValue = floor(event.Value/app.interval)*app.interval;
            xlimtemp=app.MainAxes1.XLim;
            app.MainAxes1.XLim=[changingValue,xlimtemp(2)];
            app.MainAxes1.XTick=app.MainAxes1.XLim;
            app.Slidervideo.Limits=app.MainAxes1.XLim;
            %app.Slidervideo.Value=app.MainAxes1.XLim(1);
            app.Sliderleft.MajorTicks=changingValue;
        end

        % Value changing function: Sliderright
        function SliderrightValueChanging(app, event)
            changingValue = ceil(event.Value/app.interval)*app.interval;
            xlimtemp=app.MainAxes1.XLim;
            app.MainAxes1.XLim=[xlimtemp(1),changingValue];
            app.MainAxes1.XTick=app.MainAxes1.XLim;
            app.Slidervideo.Limits=app.MainAxes1.XLim;
            app.Sliderright.MajorTicks=changingValue;
        end

        % Value changing function: Slidervideo
        function SlidervideoValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(app.tfull)
                [~,ind]=min(abs(app.tfull-changingValue));
                t=app.tfull(ind);
                app.video.currentTime=t;
                app.videoline.XData=[t t];
                
                
                try
                    app.Slidervideo.Value=t;
                    app.Slidervideo.MajorTicks=t;
                    frame=rgb2gray(readFrame(app.video));
                    imshow(frame,[0 app.ContrastSlider.Value],'Parent',app.MainAxes2);
                catch
                    disp('reached the end of the video');
                end
            else
                app.video.currentTime=changingValue;
                frame=rgb2gray(readFrame(app.video));
                imshow(frame,[0 app.ContrastSlider.Value],'Parent',app.MainAxes2);
            end
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if ~isempty(app.tfull)
                [~,ind]=min(abs(app.tfull-app.Slidervideo.Value));
                t=app.tfull(ind);
                app.video.currentTime=t;            
                while(value==true) 
                    
                    t=app.video.currentTime;               
                    if t<app.Slidervideo.Limits(2)
                        if app.Ifull(ind)<app.threshold
                            set(app.videoline,'color','r');
                        else
                            set(app.videoline,'color','k');
                        end
                       
                       
                        try                                             
                            app.videoline.XData=[app.video.currentTime app.video.currentTime];
                            app.Slidervideo.Value=app.video.currentTime;
                            app.Slidervideo.MajorTicks=app.video.currentTime;
                            app.currentFrame=rgb2gray(readFrame(app.video));
                            app.videoshown.CData=app.currentFrame;
                            %imshow(app.currentFrame,[0 app.ContrastSlider.Value],'Parent',app.MainAxes2);
                            value=app.PlayButton.Value;
                            if value==false
                                break
                            end
                            pause(app.interval);
                        catch
                            disp('reached the xlim');
                        end
                        ind=ind+1;
                        
                    else
                        break
                    end
                    
                end
            else %pure video play
                app.video.currentTime=app.Slidervideo.Value;
                while(value==true)
                    if event.Value==false
                        break
                    else
                        if hasFrame(app.video)
                            app.Slidervideo.Value=app.video.currentTime;
                            app.Slidervideo.MajorTicks=app.video.currentTime;
                            app.currentFrame=rgb2gray(readFrame(app.video));
                            app.videoshown.CData=app.currentFrame;
                            %imshow(app.currentFrame,[0 app.ContrastSlider.Value],'Parent',app.MainAxes2);
                            pause(app.interval);
                        else
                            break
                        end
                    end
                    value=app.PlayButton.Value;
                end
            end


        end

        % Callback function
        function SliderthresholdValueChanged(app, event)


        end

        % Button pushed function: AddSegmentButton
        function AddSegmentButtonPushed(app, event)
            prompt = {'Start:','Duration:','Name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'','',''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            nrow=size(app.UITable.Data,1);
            if ~isempty(app.Ifull)
                app.UITable.Data.Start(nrow+1)=str2double(answer{1});
                app.UITable.Data.Duration(nrow+1)=str2double(answer{2});
                app.UITable.Data.Name(nrow+1)={answer{3}};
                app.UITable.Data.End(nrow+1)=app.UITable.Data.Start(nrow+1)+app.UITable.Data.Duration(nrow+1);
                Itemp=app.Ifull(app.tfull>=app.UITable.Data.Start(nrow+1) & app.tfull<=app.UITable.Data.End(nrow+1));
                app.UITable.Data.Freezing(nrow+1)=ffe(app,Itemp);
            else
                if isempty(app.UITable.Data)
                    s=str2double(answer{1});
                    dur=str2double(answer{2});
                    n={answer{3}};
                    e=s+dur;
                    app.UITable.Data=table(s,dur,e,NaN,n,'VariableNames',{'Start','Duration','End','Freezing','Name'});
                else
                    app.UITable.Data.Start(nrow+1)=str2double(answer{1});
                    app.UITable.Data.Duration(nrow+1)=str2double(answer{2});
                    app.UITable.Data.Name(nrow+1)={answer{3}};
                    app.UITable.Data.End(nrow+1)=app.UITable.Data.Start(nrow+1)+app.UITable.Data.Duration(nrow+1);
                    
                    
                end
             
            end


        end

        % Callback function
        function UpdateResultButtonPushed(app, event)
            
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = app.ContrastSlider.Value;
            imshow(app.currentFrame,[0 app.ContrastSlider.Value],'Parent',app.MainAxes2);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            hold(app.MainAxes1,'off');
            hold(app.SubAxes1,'off');
            x1=app.pos_border(1);
            x2=app.pos_border(2);
            y1=app.pos_border(3);
            y2=app.pos_border(4);
            app.video.currentTime=app.Sliderleft.Value;
            app.tfull=app.video.currentTime;
            videoFrame=rgb2gray(readFrame(app.video));
            frame1=videoFrame(y1:y2,x1:x2);
            if app.ContrastSlider.Value<255
                frame1(frame1>=app.ContrastSlider.Value)=255;
            end
            app.Ifull=[];
                        
            while hasFrame(app.video)
                if app.video.currentTime<=app.Sliderright.Value
                    app.tfull=[app.tfull,app.video.currentTime];
                    videoFrame=rgb2gray(readFrame(app.video)); 
                    frame2=videoFrame(y1:y2,x1:x2,:);
                    if app.ContrastSlider.Value<255
                        frame2(frame2>=app.ContrastSlider.Value)=255;     
                    end
                    dI=abs(frame2-frame1);
                    app.Ifull=[app.Ifull,mean(dI(:))];
                    frame1=frame2;
                else
                    break
                end
            end
            app.framefull=1:length(app.tfull);
            app.Ifull=[0 app.Ifull];
            
            plot(app.MainAxes1,app.tfull,app.Ifull);
            
            app.interval=1/app.video.FrameRate;
            

            %Threshold
            app.threshold=app.EditThreshold.Value;
            %app.EditThreshold.Value=app.threshold;
            app.Sliderthreshold.Limits=app.MainAxes1.YLim;
            app.Sliderthreshold.Value=app.threshold;
            
            
            hold(app.MainAxes1,'on');
            app.MainAxes1.XLim=[app.tfull(1) app.tfull(end)];
            app.MainAxes1.YLim=[0 max(app.Ifull)*app.Sliderylim.Value];
            app.MainAxes1.XTick=[app.tfull(1) app.tfull(end)];
            app.thresline=plot(app.MainAxes1,app.MainAxes1.XLim,[app.threshold, app.threshold],'--g');
            
            app.Sliderleft.Limits=[app.tfull(1) app.tfull(end)];
            app.Sliderright.Limits=[app.tfull(1) app.tfull(end)];
            app.Slidervideo.Limits=[app.tfull(1) app.tfull(end)];
            app.Sliderleft.Value=app.tfull(1);
            app.Slidervideo.Value=app.tfull(1);
            app.Sliderright.Value=app.tfull(end);


            app.videoline=plot(app.MainAxes1,[app.tfull(1) app.tfull(1)],app.MainAxes1.YLim,'--k');
                                
            find_freezeing_epoch(app);
            freeze_c=0;         
            celldisp(app.freeze_epoch)
            if ~isempty(app.freeze_epoch)
                for i=1:length(app.freeze_epoch)
                    if ~isempty(app.freeze_epoch{i})
                        freeze_c=freeze_c+app.freeze_epoch{i}.duration;
                    end
                end
            end
            freeze_level=freeze_c/length(app.Ifull);
            
                             
            Start=app.tfull(1);
            End=app.tfull(end);
            Duration=End-Start;
            Freezing=freeze_level;
            Name={'Whole session'};
            
            d=table(Start,Duration,End,Freezing,Name);
            app.UITable.Data=d;
            
            histogram(app.Ifull,0:0.05:ceil(max(app.Ifull)),'Parent',app.SubAxes1);
            hold(app.SubAxes1,'on');
            app.thresline2=plot(app.SubAxes1,[app.threshold app.threshold],app.SubAxes1.YLim,'--g');
            app.Sliderthreshold2.Limits=[0 ceil(max(app.Ifull))];
            app.Sliderthreshold2.Value=app.threshold;
        end

        % Button pushed function: DrawEpochButton
        function DrawEpochButtonPushed(app, event)
            app.MainAxes1.cla;
            plot(app.MainAxes1,app.tfull,app.Ifull);
            hold(app.MainAxes1,'on');
            app.thresline=plot(app.MainAxes1,app.Sliderleft.Limits,[app.threshold, app.threshold],'--g');
            app.videoline=plot(app.MainAxes1,[0 0],app.MainAxes1.YLim,'--k');
            
            find_freezeing_epoch(app);         
            if ~isempty(app.freeze_epoch)
                for i=1:length(app.freeze_epoch)
                    if ~isempty(app.freeze_epoch{i})
                    area(app.MainAxes1,[app.tfull(app.freeze_epoch{i}.x1) app.tfull(app.freeze_epoch{i}.x2)],[app.MainAxes1.YLim(2) app.MainAxes1.YLim(2)],'FaceAlpha',0.4,'LineStyle','none','FaceColor','r');  

                    end
                end
            end
            update_table(app)
            
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            irow=indices(1);
            try
                if indices(2)==5
                    Start=app.UITable.Data.Start(irow);
                    End=app.UITable.Data.End(irow);
                    if Start<app.tfull(1)
                        Start=app.tfull(1);
                    end
                    if End>app.tfull(end)
                        End=app.tfull(end);
                    end
                    app.MainAxes1.XLim=[Start End];
                    app.Sliderleft.Value=Start;
                    app.Sliderright.Value=End;
                    app.Slidervideo.Limits=[Start End];
                    app.Slidervideo.Value=Start;
                    app.selectedrow=irow;
                end
            catch
                app.selectedrow=irow;
            end
        end

        % Value changed function: Slidervideo
        function SlidervideoValueChanged(app, event)
            value = app.Slidervideo.Value;
            if ~isempty(app.tfull)
                [~,ind]=min(abs(app.tfull-value));
                t=app.tfull(ind);
                app.video.currentTime=t;
                app.videoline.XData=[t t];
                           
                try
                    app.Slidervideo.Value=t;
                    app.Slidervideo.MajorTicks=t;
                    frame=rgb2gray(readFrame(app.video));
                    %imshow(frame,[0 app.ContrastSlider.Value],'Parent',app.MainAxes2);
                    app.videoshown.CData=frame;
                catch
                    disp('reached the end of the video');
                end
                app.currentFrame=frame;
            else
                app.video.currentTime=value;
                frame=rgb2gray(readFrame(app.video));
                app.videoshown.CData=frame;
                %imshow(frame,[0 app.ContrastSlider.Value],'Parent',app.MainAxes2);
            end
                
        end

        % Value changing function: Sliderthreshold2
        function Sliderthreshold2ValueChanging(app, event)
            changingValue = event.Value;
            app.thresline2.XData=[changingValue changingValue];
            app.thresline.YData=[changingValue changingValue];
            app.threshold=changingValue;
            app.EditThreshold.Value=changingValue;
        end

        % Button pushed function: DeleteSegmentButton
        function DeleteSegmentButtonPushed(app, event)

            app.UITable.Data(app.selectedrow,:)=[];
        end

        % Menu selected function: ImportTemplateMenu
        function ImportTemplateMenuSelected(app, event)
            [file,path] = uigetfile({'*template.xlsx;*template.csv'},'Template Selector');
            app.template=readtable(fullfile(path,file));
            app.UITable.Data=app.template;
            if ~isempty(app.Ifull)
                update_table(app);
            end
        end

        % Value changed function: EditThreshold
        function EditThresholdValueChanged(app, event)
            value = app.EditThreshold.Value;
            app.threshold=value;
            app.Sliderthreshold.Value=value;
            app.Sliderthreshold2.Value=value;
            app.thresline.YData=[value value];
            app.thresline2.XData=[value value];
            update_table(app);
        end

        % Menu selected function: BatchImportMenu
        function BatchImportMenuSelected(app, event)
             [app.batchfilename,app.pathname] = uigetfile({'*.avi;*.mp4;*.wmv;*.asf'},'Batch Video File Selector','MultiSelect','on');
             app.FileList.Items=unique(cat(2,app.FileList.Items,app.batchfilename));   
             app.ImportTemplateMenu.Enable='on';
             if exist('Config.txt','file')
                 disp('Config file found');
                 fileID = fopen('Config.txt','r');
                 formatSpec = '%s %f';
                 A = textscan(fileID,formatSpec);
                 app.threshold=A{2}(1);
                 app.EditThreshold.Value=app.threshold;
                 app.Sliderthreshold.Value=app.threshold;
                 app.Sliderthreshold2.Value=app.threshold;
                 app.MinimumFreezeDurationSpinner.Value=A{2}(2);
                 app.ContrastSlider.Value=A{2}(3);                    
                 fclose(fileID);
             else
                 disp('No config file found');
             end
        end

        % Button pushed function: FineTuneButton
        function FineTuneButtonPushed(app, event)
            %crop the image
            app.Ifull=[];
            app.tfull=[];
            app.MainAxes1.cla;
            app.filename=app.FileList.Value;
            app.video=VideoReader(fullfile(app.pathname,app.filename));
            ti=app.video.currentTime;
            videoFrame=rgb2gray(readFrame(app.video));
            %videoFrame=rgb2gray(videoFrame);
            if isempty(app.pos_border)
                tempfig=figure('units','pixels',...
                        'position',[100 300 800 600],...
                        'menubar','none',...
                        'name','ROI selection window');
                imshow(videoFrame);
                try
                    h_rect = drawrectangle();
                    app.pos_rect = round(h_rect.Position);
                catch
                    h_rect = imrect;
                    app.pos_rect = wait(h_rect);
                end
                x1=app.pos_rect(1);x2=app.pos_rect(1)+app.pos_rect(3);
                y1=app.pos_rect(2);y2=app.pos_rect(2)+app.pos_rect(4);  
                app.pos_border=[x1,x2,y1,y2];
                close(tempfig);
            else
                x1=app.pos_border(1);
                x2=app.pos_border(2);
                y1=app.pos_border(3);
                y2=app.pos_border(4);
            end
            
            app.videoshown=imshow(videoFrame,[0 app.ContrastSlider.Value],'Parent',app.MainAxes2);
            app.currentFrame=videoFrame;
            app.MainAxes2.YLim=[y1 y2];
            app.MainAxes2.XLim=[x1 x2];
            app.interval=1/app.video.FrameRate;
            app.Sliderright.Limits=[0 app.video.duration];
            app.Sliderright.Value=app.video.duration;
            app.Sliderleft.Limits=[0 app.video.duration];
            app.Sliderleft.Value=0;
            app.Sliderright.MajorTicks=[];
            app.Slidervideo.Limits=[0 app.video.duration];
            app.Slidervideo.Value=0;
            app.MainAxes1.XLim=[0 app.video.duration];
            
            app.ImportTemplateMenu.Enable = 'on';
            app.AnalyzeButton.Enable='on';
            app.DrawEpochButton.Enable='on';
        end

        % Menu selected function: BatchAnalyzeMenu
        function BatchAnalyzeMenuSelected(app, event)
            app.threshold=app.EditThreshold.Value; %make sure there is threshold
            answer = questdlg('Please check the template', ...
        	'Alert!', ...
        	'Proceed','Cancel','Proceed');
            if strcmp(answer,'Proceed')
                app.results=cell(length(app.batchfilename),1);
                app.I=cell(length(app.batchfilename),1);
                app.ts=cell(length(app.batchfilename),1);
                for i=1:length(app.batchfilename)
                    app.FileList.Value=app.FileList.Items{i}; %To show you which file it is processing now;
                    app.video=VideoReader(fullfile(app.pathname,app.batchfilename{i}));
                    app.interval=1/app.video.FrameRate;
                    [app.ts{i},app.I{i}]=batchanalysis(app);
                    app.results{i}=app.UITable.Data;
                end
                app.ViewResultButton.Enable='on';
                
                %save .mat file 
                matfilename= sprintf('%s_results_%s.mat',app.ExperimentNameEditField.Value, datestr(now,'mm_dd_yyyy_HH_MM'));
                newfile=fullfile(app.pathname,matfilename);
                MI=app.I;
                TS=app.ts;
                Threshold=app.threshold;
                MIN_DUR_THRES=app.MinimumFreezeDurationSpinner.Value;
                Freezing_result=app.results;
                Contrast=app.ContrastSlider.Value;
                VideoFilename=app.batchfilename;
                VideoFilepath=app.pathname;
                ExperimentName=app.ExperimentNameEditField.Value;
                Arena=app.pos_border;
                save(newfile,'MI','TS','Threshold','MIN_DUR_THRES','Freezing_result','Contrast','VideoFilename','VideoFilepath','ExperimentName','Arena');
            end
           
        end

        % Menu selected function: SetROIMenu
        function SetROIMenuSelected(app, event)
            try
                tempfig=figure('units','pixels',...
                            'position',[100 300 800 600],...
                            'menubar','none',...
                            'name','ROI selection window');
                v=VideoReader(fullfile(app.pathname,app.FileList.Value));
                videoFrame=rgb2gray(readFrame(v));
                imshow(videoFrame);
                try
                    h_rect = drawrectangle();
                    app.pos_rect = round(h_rect.Position);
                catch
                    h_rect = imrect;
                    app.pos_rect = wait(h_rect);
                end
                x1=app.pos_rect(1);x2=app.pos_rect(1)+app.pos_rect(3);
                y1=app.pos_rect(2);y2=app.pos_rect(2)+app.pos_rect(4);  
                app.pos_border=[x1,x2,y1,y2];
                close(tempfig);
                app.MainAxes2.YLim=[y1 y2];
                app.MainAxes2.XLim=[x1 x2];
            catch
                questdlg('Please set the arena', ...
        	'Alert!', ...
        	'OK','Cancel','OK');
            end
        end

        % Button pushed function: ViewResultButton
        function ViewResultButtonPushed(app, event)
            app.MainAxes1.cla;
            app.SubAxes1.cla;
            app.video=VideoReader(fullfile(app.pathname,app.FileList.Value));
            app.interval=1/app.video.FrameRate;
            ind=find(ismember(app.FileList.Items,app.FileList.Value));
            app.Ifull=app.I{ind};
            app.tfull=app.ts{ind};
            app.UITable.Data=app.results{ind};
            app.currentFrame=rgb2gray(readFrame(app.video));
            imshow(app.currentFrame,[0 app.ContrastSlider.Value],'Parent',app.MainAxes2);
            
            hold(app.MainAxes1,'on');
            plot(app.MainAxes1,app.tfull,app.Ifull);
            app.MainAxes1.XLim=[app.tfull(1) app.tfull(end)];
            app.MainAxes1.YLim=[0 max(app.Ifull)*app.Sliderylim.Value];
            app.MainAxes1.XTick=[app.tfull(1) app.tfull(end)];
            app.thresline=plot(app.MainAxes1,app.MainAxes1.XLim,[app.threshold, app.threshold],'--g');
            
            app.Sliderleft.Limits=[app.tfull(1) app.tfull(end)];
            app.Sliderright.Limits=[app.tfull(1) app.tfull(end)];
            app.Slidervideo.Limits=[app.tfull(1) app.tfull(end)];
            app.Sliderleft.Value=app.tfull(1);
            app.Slidervideo.Value=app.tfull(1);
            app.Sliderright.Value=app.tfull(end);
                 
            app.videoline=plot(app.MainAxes1,[app.tfull(1) app.tfull(1)],app.MainAxes1.YLim,'--k');
            histogram(app.Ifull,0:0.05:ceil(max(app.Ifull)),'Parent',app.SubAxes1);
            hold(app.SubAxes1,'on');
            app.thresline2=plot(app.SubAxes1,[app.threshold app.threshold],app.SubAxes1.YLim,'--g');
            app.Sliderthreshold2.Limits=[0 ceil(max(app.Ifull))];
            app.Sliderthreshold2.Value=app.threshold;
            
            app.Sliderthreshold.Limits=app.MainAxes1.YLim;
            app.Sliderthreshold.Value=app.threshold;
        end

        % Menu selected function: ImportMATFileMenu
        function ImportMATFileMenuSelected(app, event)
            [newfile,newpath] = uigetfile('*.mat','MAT File Selector');
            data=load(fullfile(newpath,newfile));
            app.results=data.Freezing_result;
            app.I=data.MI;
            app.ts=data.TS;
            app.threshold=data.Threshold;
            app.Sliderthreshold.Value=app.threshold;
            app.Sliderthreshold2.Value=app.threshold;
            app.EditThreshold.Value=app.threshold;
            app.MinimumFreezeDurationSpinner.Value=data.MIN_DUR_THRES;
            app.ContrastSlider.Value=data.Contrast;
            app.batchfilename=data.VideoFilename;
            app.pathname=data.VideoFilepath;
            app.FileList.Items=app.batchfilename;
            app.FileList.Value=app.FileList.Items{1};
            app.ViewResultButton.Enable='on';
            app.pos_border=data.Arena;
            app.ExperimentNameEditField.Value=data.ExperimentName;
            x1=app.pos_border(1);
            x2=app.pos_border(2);
            y1=app.pos_border(3);
            y2=app.pos_border(4);
            app.MainAxes2.YLim=[y1,y2];
            app.MainAxes2.XLim=[x1,x2];
            app.DrawEpochButton.Enable='on';
            app.FineTuneButton.Enable='off';
        end

        % Menu selected function: IndividualFileMenu
        function IndividualFileMenuSelected(app, event)
            
            if length(app.FileList.Items)==1
                resultfilename=[app.filename(1:find(app.filename=='.')-1) '_result.xls'];
                newfile=fullfile(app.pathname,resultfilename);
                writetable(app.UITable.Data,newfile);
            else
                for i=1:length(app.batchfilename)
                    filetemp=app.batchfilename{i};
                    resultfilename=[filetemp(1:find(filetemp=='.')-1) '_result.xls'];
                    newfile=fullfile(app.pathname,resultfilename);
                    writetable(app.results{i},newfile);                    
                end
            end
            
            if exist('Config.txt','file')
                fileID = fopen('Config.txt','w');
                formatSpec = '%s %3f \n';
                fprintf(fileID,formatSpec,'MI_threshold', app.threshold);                
                fprintf(fileID,'%s %d \n','Min_Freeze_Dur', app.MinimumFreezeDurationSpinner.Value);
                fprintf(fileID,'%s %d \n','Contrast', app.ContrastSlider.Value);
                fclose(fileID); 
            end
        end

        % Menu selected function: SummaryFileMenu
        function SummaryFileMenuSelected(app, event)
            File=app.batchfilename';
            Freeze=zeros(length(app.results),1);
            for i=1:length(app.results)
                Freeze(i)=app.results{i}.Freezing;
            end
            newtable=table(File,Freeze);
            newfilename= sprintf('%s_results_%s.xls',app.ExperimentNameEditField.Value, datestr(now,'mm_dd_yyyy_HH_MM'));
            newfile=fullfile(app.pathname,newfilename);
            writetable(newtable,newfile);
            
        end

        % Menu selected function: SaveTemplateMenu
        function SaveTemplateMenuSelected(app, event)
            templatefilename=sprintf('%s_template.csv',app.ExperimentNameEditField.Value);
            writetable(app.UITable.Data,fullfile(app.pathname,templatefilename));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ImportVideoMenu = uimenu(app.Menu);
            app.ImportVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportVideoMenuSelected, true);
            app.ImportVideoMenu.Text = 'Import Video';
            app.ImportMATFileMenu = uimenu(app.Menu);
            app.ImportMATFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMATFileMenuSelected, true);
            app.ImportMATFileMenu.Text = 'Import MAT File';
            app.ImportTemplateMenu = uimenu(app.Menu);
            app.ImportTemplateMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportTemplateMenuSelected, true);
            app.ImportTemplateMenu.Enable = 'off';
            app.ImportTemplateMenu.Text = 'Import Template';
            app.SaveResultMenu = uimenu(app.Menu);
            app.SaveResultMenu.Text = 'Save Result';
            app.SummaryFileMenu = uimenu(app.SaveResultMenu);
            app.SummaryFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SummaryFileMenuSelected, true);
            app.SummaryFileMenu.Text = 'Summary File';
            app.IndividualFileMenu = uimenu(app.SaveResultMenu);
            app.IndividualFileMenu.MenuSelectedFcn = createCallbackFcn(app, @IndividualFileMenuSelected, true);
            app.IndividualFileMenu.Text = 'Individual File';
            app.SaveTemplateMenu = uimenu(app.Menu);
            app.SaveTemplateMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveTemplateMenuSelected, true);
            app.SaveTemplateMenu.Text = 'Save Template';
            app.BatchMenu = uimenu(app.UIFigure);
            app.BatchMenu.Text = 'Batch';
            app.BatchImportMenu = uimenu(app.BatchMenu);
            app.BatchImportMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchImportMenuSelected, true);
            app.BatchImportMenu.Text = 'Batch Import';
            app.BatchAnalyzeMenu = uimenu(app.BatchMenu);
            app.BatchAnalyzeMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchAnalyzeMenuSelected, true);
            app.BatchAnalyzeMenu.Text = 'Batch Analyze';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.SetROIMenu = uimenu(app.EditMenu);
            app.SetROIMenu.MenuSelectedFcn = createCallbackFcn(app, @SetROIMenuSelected, true);
            app.SetROIMenu.Text = 'Set ROI';
            app.VideoPanel = uipanel(app.UIFigure);
            app.VideoPanel.Title = 'Video Panel';
            app.MainAxes1 = uiaxes(app.VideoPanel);
            title(app.MainAxes1, 'Motion Signal')
            xlabel(app.MainAxes1, '')
            ylabel(app.MainAxes1, {'Motion Index'; ''})
            app.MainAxes1.XTick = [];
            app.Sliderleft = uislider(app.VideoPanel);
            app.Sliderleft.Limits = [1 100];
            app.Sliderleft.MajorTicks = [];
            app.Sliderleft.ValueChangingFcn = createCallbackFcn(app, @SliderleftValueChanging, true);
            app.Sliderleft.MinorTicks = [];
            app.Sliderleft.Value = 1;
            app.Sliderright = uislider(app.VideoPanel);
            app.Sliderright.Limits = [1 100];
            app.Sliderright.MajorTicks = [];
            app.Sliderright.ValueChangingFcn = createCallbackFcn(app, @SliderrightValueChanging, true);
            app.Sliderright.MinorTicks = [];
            app.Sliderright.Value = 100;
            app.Slidervideo = uislider(app.VideoPanel);
            app.Slidervideo.Limits = [1 100];
            app.Slidervideo.MajorTicks = [];
            app.Slidervideo.ValueChangedFcn = createCallbackFcn(app, @SlidervideoValueChanged, true);
            app.Slidervideo.ValueChangingFcn = createCallbackFcn(app, @SlidervideoValueChanging, true);
            app.Slidervideo.MinorTicks = [];
            app.Slidervideo.Value = 1;
            app.NextButton = uibutton(app.VideoPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '>';
            app.PreviousButton = uibutton(app.VideoPanel, 'push');
            app.PreviousButton.Text = '<';
            app.PlayButton = uibutton(app.VideoPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.Sliderthreshold = uislider(app.VideoPanel);
            app.Sliderthreshold.MajorTicks = [];
            app.Sliderthreshold.ValueChangingFcn = createCallbackFcn(app, @SliderthresholdValueChanging, true);
            app.Sliderthreshold.MinorTicks = [];
            app.Sliderthreshold.Value = 0.3;
            app.Sliderylim = uislider(app.VideoPanel);
            app.Sliderylim.Limits = [0 1];
            app.Sliderylim.MajorTicks = [];
            app.Sliderylim.ValueChangingFcn = createCallbackFcn(app, @SliderylimValueChanging, true);
            app.Sliderylim.MinorTicks = [];
            app.Sliderylim.Value = 0.5;
            app.MainAxes2 = uiaxes(app.VideoPanel);
            title(app.MainAxes2, '')
            xlabel(app.MainAxes2, '')
            ylabel(app.MainAxes2, '')
            app.MainAxes2.XTick = [];
            app.MainAxes2.YTick = [];
            app.MinimumFreezeDurationSpinnerLabel = uilabel(app.VideoPanel);
            app.MinimumFreezeDurationSpinnerLabel.Text = 'Minimum Freeze Duration';
            app.MinimumFreezeDurationSpinner = uispinner(app.VideoPanel);
            app.MinimumFreezeDurationSpinner.Step = 0.5;
            app.MinimumFreezeDurationSpinner.Limits = [0 10];
            app.MinimumFreezeDurationSpinner.Value = 1;
            app.ContrastSliderLabel = uilabel(app.VideoPanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.VideoPanel);
            app.ContrastSlider.Limits = [0 255];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = 40;
            app.AnalyzeButton = uibutton(app.VideoPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Enable = 'off';
            app.AnalyzeButton.Text = 'Analyze';
            app.DrawEpochButton = uibutton(app.VideoPanel, 'push');
            app.DrawEpochButton.ButtonPushedFcn = createCallbackFcn(app, @DrawEpochButtonPushed, true);
            app.DrawEpochButton.Enable = 'off';
            app.DrawEpochButton.Text = 'Draw Epoch';
            app.EditThreshold = uieditfield(app.VideoPanel, 'numeric');
            app.EditThreshold.ValueDisplayFormat = '%.2f';
            app.EditThreshold.ValueChangedFcn = createCallbackFcn(app, @EditThresholdValueChanged, true);
            app.EditThreshold.Value = 0.4;
            app.AddSegmentButton = uibutton(app.UIFigure, 'push');
            app.AddSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @AddSegmentButtonPushed, true);
            app.AddSegmentButton.Text = 'Add Segment';
            app.SubAxes1 = uiaxes(app.UIFigure);
            title(app.SubAxes1, 'MI Distribution')
            xlabel(app.SubAxes1, '')
            ylabel(app.SubAxes1, 'Count')
            app.Sliderthreshold2 = uislider(app.UIFigure);
            app.Sliderthreshold2.MajorTicks = [];
            app.Sliderthreshold2.ValueChangingFcn = createCallbackFcn(app, @Sliderthreshold2ValueChanging, true);
            app.Sliderthreshold2.MinorTicks = [];
            app.Sliderthreshold2.Value = 0.4;
            app.DeleteSegmentButton = uibutton(app.UIFigure, 'push');
            app.DeleteSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSegmentButtonPushed, true);
            app.DeleteSegmentButton.Text = 'Delete Segment';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Start'; 'Duration'; 'End'; 'Freezing'; 'Name'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.FileList = uilistbox(app.UIFigure);
            app.FileList.Items = {};
            app.FileList.Value = {};
            app.FineTuneButton = uibutton(app.UIFigure, 'push');
            app.FineTuneButton.ButtonPushedFcn = createCallbackFcn(app, @FineTuneButtonPushed, true);
            app.FineTuneButton.Text = 'Fine Tune';
            app.ViewResultButton = uibutton(app.UIFigure, 'push');
            app.ViewResultButton.ButtonPushedFcn = createCallbackFcn(app, @ViewResultButtonPushed, true);
            app.ViewResultButton.Enable = 'off';
            app.ViewResultButton.Text = 'View Result';
            app.ExperimentNameEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name';
            app.ExperimentNameEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentNameEditField.Value = 'CFC_conditioning';
        end
    end

    methods (Access = public)

        % Construct app
        function app = freezing_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end